classdef finalProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        InputParametersTab      matlab.ui.container.Tab
        LaminateMaterialPropertiesPanel  matlab.ui.container.Panel
        E1EditFieldLabel        matlab.ui.control.Label
        E1EditField             matlab.ui.control.NumericEditField
        E2EditFieldLabel        matlab.ui.control.Label
        E2EditField             matlab.ui.control.NumericEditField
        G12EditFieldLabel       matlab.ui.control.Label
        G12EditField            matlab.ui.control.NumericEditField
        v12EditFieldLabel       matlab.ui.control.Label
        v12EditField            matlab.ui.control.NumericEditField
        alpha1EditFieldLabel    matlab.ui.control.Label
        alpha1EditField         matlab.ui.control.NumericEditField
        alpha2EditFieldLabel    matlab.ui.control.Label
        alpha2EditField         matlab.ui.control.NumericEditField
        DropDown                matlab.ui.control.DropDown
        LaminateStrengthPropertiesPanel  matlab.ui.container.Panel
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        XEditField_2Label       matlab.ui.control.Label
        XEditField_2            matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        YEditField_2Label       matlab.ui.control.Label
        YEditField_2            matlab.ui.control.NumericEditField
        SEditFieldLabel         matlab.ui.control.Label
        SEditField              matlab.ui.control.NumericEditField
        StrengthStressLabel     matlab.ui.control.Label
        StrengthStrainLabel     matlab.ui.control.Label
        XeEditFieldLabel        matlab.ui.control.Label
        XeEditField             matlab.ui.control.NumericEditField
        DropDown_2              matlab.ui.control.DropDown
        XeEditField_2Label      matlab.ui.control.Label
        XeEditField_2           matlab.ui.control.NumericEditField
        YeEditFieldLabel        matlab.ui.control.Label
        YeEditField             matlab.ui.control.NumericEditField
        YeEditField_2Label      matlab.ui.control.Label
        YeEditField_2           matlab.ui.control.NumericEditField
        SeEditFieldLabel        matlab.ui.control.Label
        SeEditField             matlab.ui.control.NumericEditField
        CompositeMechanicalLoadingConditionsPanel  matlab.ui.container.Panel
        NxEditFieldLabel        matlab.ui.control.Label
        NxEditField             matlab.ui.control.NumericEditField
        NyEditFieldLabel        matlab.ui.control.Label
        NyEditField             matlab.ui.control.NumericEditField
        NxyEditFieldLabel       matlab.ui.control.Label
        NxyEditField            matlab.ui.control.NumericEditField
        MxEditFieldLabel        matlab.ui.control.Label
        MxEditField             matlab.ui.control.NumericEditField
        MyEditFieldLabel        matlab.ui.control.Label
        MyEditField             matlab.ui.control.NumericEditField
        MxyEditFieldLabel       matlab.ui.control.Label
        MxyEditField            matlab.ui.control.NumericEditField
        DropDown_4              matlab.ui.control.DropDown
        DropDown_5              matlab.ui.control.DropDown
        CompositeAnalysisInputParametersLabel  matlab.ui.control.Label
        CompositeHygrothermalLoadingConditionsPanel  matlab.ui.container.Panel
        T0EditFieldLabel        matlab.ui.control.Label
        T0EditField             matlab.ui.control.NumericEditField
        TfEditFieldLabel        matlab.ui.control.Label
        TfEditField             matlab.ui.control.NumericEditField
        DropDown_3              matlab.ui.control.DropDown
        ExcelDataOptionalPanel  matlab.ui.container.Panel
        IfSelectingFileOtherThanAE535InputDataxlsmLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        IffileAE535InputDataxlsmisinCurrentWorkingDirectoryLabel  matlab.ui.control.Label
        LoadDataButton          matlab.ui.control.Button
        DirecotryTextAreaLabel  matlab.ui.control.Label
        DirecotryTextArea       matlab.ui.control.TextArea
        LoadingTab              matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            [file, path] = uigetfile('*xlsm');
            
            if isequal(file, 0)
                
                app.DirecotryTextArea.Value = 'No File Selected';
                
            else
                
                app.DirecotryTextArea.Value = strcat(path, file);
                
            end
            
            app.DirecotryTextArea.Visible = 'on';
            app.DirecotryTextAreaLabel.Visible = 'on';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputParametersTab = uitab(app.TabGroup);
            app.InputParametersTab.Title = 'Input Parameters';
            app.LaminateMaterialPropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminateMaterialPropertiesPanel.Title = 'Laminate Material Properties';
            app.E1EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.E1EditFieldLabel.Text = 'E1 ';
            app.E1EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.E2EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.G12EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.G12EditFieldLabel.Text = 'G12';
            app.G12EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.v12EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.v12EditFieldLabel.Text = 'v12';
            app.v12EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.DropDown = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDown.Items = {'Msi', 'GPa'};
            app.DropDown.Value = 'Msi';
            app.LaminateStrengthPropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminateStrengthPropertiesPanel.Title = 'Laminate Strength Properties';
            app.XEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.XEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XEditField_2Label.Text = 'X''';
            app.XEditField_2 = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YEditField_2Label.Text = 'Y''';
            app.YEditField_2 = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.SEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.StrengthStressLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.StrengthStressLabel.Text = 'Strength (Stress)';
            app.StrengthStrainLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.StrengthStrainLabel.Text = 'Strength (Strain)';
            app.XeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XeEditFieldLabel.Text = 'Xe';
            app.XeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.DropDown_2 = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDown_2.Items = {'Msi', 'kPa'};
            app.DropDown_2.Value = 'Msi';
            app.XeEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XeEditField_2Label.Text = 'X''e';
            app.XeEditField_2 = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YeEditFieldLabel.Text = 'Ye';
            app.YeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YeEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YeEditField_2Label.Text = 'Y''e';
            app.YeEditField_2 = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.SeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.SeEditFieldLabel.Text = 'Se';
            app.SeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.CompositeMechanicalLoadingConditionsPanel = uipanel(app.InputParametersTab);
            app.CompositeMechanicalLoadingConditionsPanel.Title = 'Composite Mechanical Loading Conditions';
            app.NxEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.NyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.NxyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NxyEditFieldLabel.Text = 'Nxy';
            app.NxyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MxEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MxEditFieldLabel.Text = 'Mx';
            app.MxEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MyEditFieldLabel.Text = 'My';
            app.MyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MxyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MxyEditFieldLabel.Text = 'Mxy';
            app.MxyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.DropDown_4 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_4.Items = {'lb', 'N'};
            app.DropDown_4.Value = 'lb';
            app.DropDown_5 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_5.Items = {'lbin', 'Nm'};
            app.DropDown_5.Value = 'lbin';
            app.CompositeAnalysisInputParametersLabel = uilabel(app.InputParametersTab);
            app.CompositeAnalysisInputParametersLabel.Text = 'Composite Analysis Input Parameters';
            app.CompositeHygrothermalLoadingConditionsPanel = uipanel(app.InputParametersTab);
            app.CompositeHygrothermalLoadingConditionsPanel.Title = 'Composite Hygrothermal Loading Conditions';
            app.T0EditFieldLabel = uilabel(app.CompositeHygrothermalLoadingConditionsPanel);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.CompositeHygrothermalLoadingConditionsPanel, 'numeric');
            app.TfEditFieldLabel = uilabel(app.CompositeHygrothermalLoadingConditionsPanel);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.CompositeHygrothermalLoadingConditionsPanel, 'numeric');
            app.DropDown_3 = uidropdown(app.CompositeHygrothermalLoadingConditionsPanel);
            app.DropDown_3.Items = {'1/F', '1/C'};
            app.DropDown_3.Value = '1/F';
            app.ExcelDataOptionalPanel = uipanel(app.InputParametersTab);
            app.ExcelDataOptionalPanel.Title = 'Excel Data (Optional)';
            app.IfSelectingFileOtherThanAE535InputDataxlsmLabel = uilabel(app.ExcelDataOptionalPanel);
            app.IfSelectingFileOtherThanAE535InputDataxlsmLabel.Text = 'If Selecting File Other Than "AE535 - Input Data.xlsm" : ';
            app.BrowseButton = uibutton(app.ExcelDataOptionalPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.IffileAE535InputDataxlsmisinCurrentWorkingDirectoryLabel = uilabel(app.ExcelDataOptionalPanel);
            app.IffileAE535InputDataxlsmisinCurrentWorkingDirectoryLabel.Text = 'If file "AE 535 - Input Data.xlsm" is in Current Working Directory: ';
            app.LoadDataButton = uibutton(app.ExcelDataOptionalPanel, 'push');
            app.LoadDataButton.Text = 'Load Data';
            app.DirecotryTextAreaLabel = uilabel(app.ExcelDataOptionalPanel);
            app.DirecotryTextAreaLabel.Visible = 'off';
            app.DirecotryTextAreaLabel.Text = 'Direcotry:';
            app.DirecotryTextArea = uitextarea(app.ExcelDataOptionalPanel);
            app.DirecotryTextArea.Visible = 'off';
            app.LoadingTab = uitab(app.TabGroup);
            app.LoadingTab.Title = 'Loading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end